classdef initialConditions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        aEditFieldLabel        matlab.ui.control.Label
        aEditField             matlab.ui.control.NumericEditField
        eEditFieldLabel        matlab.ui.control.Label
        eEditField             matlab.ui.control.NumericEditField
        inclEditFieldLabel     matlab.ui.control.Label
        inclEditField          matlab.ui.control.NumericEditField
        RAEditFieldLabel       matlab.ui.control.Label
        RAEditField            matlab.ui.control.NumericEditField
        wEditFieldLabel        matlab.ui.control.Label
        wEditField             matlab.ui.control.NumericEditField
        TAEditFieldLabel       matlab.ui.control.Label
        TAEditField            matlab.ui.control.NumericEditField
        Rx_eciEditFieldLabel   matlab.ui.control.Label
        Rx_eciEditField        matlab.ui.control.NumericEditField
        Ry_eciEditFieldLabel   matlab.ui.control.Label
        Ry_eciEditField        matlab.ui.control.NumericEditField
        Rz_eciEditFieldLabel   matlab.ui.control.Label
        Rz_eciEditField        matlab.ui.control.NumericEditField
        Vx_eciEditFieldLabel   matlab.ui.control.Label
        Vx_eciEditField        matlab.ui.control.NumericEditField
        Vy_eciEditFieldLabel   matlab.ui.control.Label
        Vy_eciEditField        matlab.ui.control.NumericEditField
        Vz_eciEditFieldLabel   matlab.ui.control.Label
        Vz_eciEditField        matlab.ui.control.NumericEditField
        Rx_ecefEditFieldLabel  matlab.ui.control.Label
        Rx_ecefEditField       matlab.ui.control.NumericEditField
        Ry_ecefEditFieldLabel  matlab.ui.control.Label
        Ry_ecefEditField       matlab.ui.control.NumericEditField
        Rz_ecefEditFieldLabel  matlab.ui.control.Label
        Rz_ecefEditField       matlab.ui.control.NumericEditField
        Vx_ecefEditFieldLabel  matlab.ui.control.Label
        Vx_ecefEditField       matlab.ui.control.NumericEditField
        Vy_ecefEditFieldLabel  matlab.ui.control.Label
        Vy_ecefEditField       matlab.ui.control.NumericEditField
        Vz_ecefEditFieldLabel  matlab.ui.control.Label
        Vz_ecefEditField       matlab.ui.control.NumericEditField
        RightPanel             matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function updateEciValues(app)
            mu = 3.986004418e14;
            [r_eci, v_eci] = kep2eci(app.aEditField.Value, ...
                                   app.eEditField.Value, ...
                                   app.RAEditField.Value, ...
                                   app.inclEditField.Value, ...
                                   app.wEditField.Value, ...
                                   app.TAEditField.Value, ...
                                   mu);
            app.Rx_eciEditField.Value = r_eci(1);
            app.Ry_eciEditField.Value = r_eci(2);
            app.Rz_eciEditField.Value = r_eci(3);
            app.Vx_eciEditField.Value = v_eci(1);
            app.Vy_eciEditField.Value = v_eci(2);
            app.Vz_eciEditField.Value = v_eci(3);
        end
        
        function updateEcefValues(app)
            [r_ecef, v_ecef] = eci2ecef([2005 2 2 12 0 0], ...
                                      [app.Rx_eciEditField.Value, ...
                                       app.Ry_eciEditField.Value, ...
                                       app.Rz_eciEditField.Value], ...
                                      [app.Vx_eciEditField.Value, ...
                                       app.Vy_eciEditField.Value, ...
                                       app.Vz_eciEditField.Value]);
            app.Rx_ecefEditField.Value = r_ecef(1);
            app.Ry_ecefEditField.Value = r_ecef(2);
            app.Rz_ecefEditField.Value = r_ecef(3);
            app.Vx_ecefEditField.Value = v_ecef(1);
            app.Vy_ecefEditField.Value = v_ecef(2);
            app.Vz_ecefEditField.Value = v_ecef(3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RAEditField, TAEditField, 
        % aEditField, eEditField, inclEditField, wEditField
        function updateFromKep(app, event)
            mu = 3.986004418e14;
            [r_eci, v_eci] = kep2eci(app.aEditField.Value, ...
                                     app.eEditField.Value, ...
                                     app.RAEditField.Value, ...
                                     app.inclEditField.Value, ...
                                     app.wEditField.Value, ...
                                     app.TAEditField.Value, ...
                                     mu);
            app.Rx_eciEditField.Value = r_eci(1);
            app.Ry_eciEditField.Value = r_eci(2);
            app.Rz_eciEditField.Value = r_eci(3);
            app.Vx_eciEditField.Value = v_eci(1);
            app.Vy_eciEditField.Value = v_eci(2);
            app.Vz_eciEditField.Value = v_eci(3);
            [r_ecef, v_ecef] = eci2ecef([2005 2 2 12 0 0], ...
                                        [app.Rx_eciEditField.Value, ...
                                         app.Ry_eciEditField.Value, ...
                                         app.Rz_eciEditField.Value], ...
                                        [app.Vx_eciEditField.Value, ...
                                         app.Vy_eciEditField.Value, ...
                                         app.Vz_eciEditField.Value]);
            app.Rx_ecefEditField.Value = r_ecef(1);
            app.Ry_ecefEditField.Value = r_ecef(2);
            app.Rz_ecefEditField.Value = r_ecef(3);
            app.Vx_ecefEditField.Value = v_ecef(1);
            app.Vy_ecefEditField.Value = v_ecef(2);
            app.Vz_ecefEditField.Value = v_ecef(3);
        end

        % Value changed function: Rx_eciEditField, Ry_eciEditField, 
        % Rz_eciEditField, Vx_eciEditField, Vy_eciEditField, 
        % Vz_eciEditField
        function updateFromEci(app, event)
            [r_ecef, v_ecef] = eci2ecef([2005 2 2 12 0 0], ...
                                        [app.Rx_eciEditField.Value, ...
                                         app.Ry_eciEditField.Value, ...
                                         app.Rz_eciEditField.Value], ...
                                        [app.Vx_eciEditField.Value, ...
                                         app.Vy_eciEditField.Value, ...
                                         app.Vz_eciEditField.Value]);
            app.Rx_ecefEditField.Value = r_ecef(1);
            app.Ry_ecefEditField.Value = r_ecef(2);
            app.Rz_ecefEditField.Value = r_ecef(3);
            app.Vx_ecefEditField.Value = v_ecef(1);
            app.Vy_ecefEditField.Value = v_ecef(2);
            app.Vz_ecefEditField.Value = v_ecef(3);
            % here do Eci -> Kep update
        end

        % Value changed function: Rx_ecefEditField, 
        % Ry_ecefEditField, Rz_ecefEditField, Vx_ecefEditField, 
        % Vy_ecefEditField, Vz_ecefEditField
        function updateFromEcef(app, event)
            [r_eci, v_eci] = ecef2eci([2005 2 2 12 0 0], ...
                                      [app.Rx_ecefEditField.Value, ...
                                       app.Ry_ecefEditField.Value, ...
                                       app.Rz_ecefEditField.Value], ...
                                      [app.Vx_ecefEditField.Value, ...
                                       app.Vy_ecefEditField.Value, ...
                                       app.Vz_ecefEditField.Value]);
            app.Rx_eciEditField.Value = r_eci(1);
            app.Ry_eciEditField.Value = r_eci(2);
            app.Rz_eciEditField.Value = r_eci(3);
            app.Vx_eciEditField.Value = v_eci(1);
            app.Vy_eciEditField.Value = v_eci(2);
            app.Vz_eciEditField.Value = v_eci(3);
            % here do Eci -> Kep update
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {311, 311};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {650, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromKep, true);
            app.aEditField.Value = 8000000;
            app.eEditFieldLabel = uilabel(app.LeftPanel);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.LeftPanel, 'numeric');
            app.eEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromKep, true);
            app.inclEditFieldLabel = uilabel(app.LeftPanel);
            app.inclEditFieldLabel.Text = 'incl';
            app.inclEditField = uieditfield(app.LeftPanel, 'numeric');
            app.inclEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromKep, true);
            app.inclEditField.Value = 0.7854;
            app.RAEditFieldLabel = uilabel(app.LeftPanel);
            app.RAEditFieldLabel.Text = 'RA';
            app.RAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RAEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromKep, true);
            app.RAEditField.Value = 3.1416;
            app.wEditFieldLabel = uilabel(app.LeftPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.LeftPanel, 'numeric');
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromKep, true);
            app.TAEditFieldLabel = uilabel(app.LeftPanel);
            app.TAEditFieldLabel.Text = 'TA';
            app.TAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TAEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromKep, true);
            app.Rx_eciEditFieldLabel = uilabel(app.LeftPanel);
            app.Rx_eciEditFieldLabel.Text = 'Rx_eci';
            app.Rx_eciEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Rx_eciEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEci, true);
            app.Ry_eciEditFieldLabel = uilabel(app.LeftPanel);
            app.Ry_eciEditFieldLabel.Text = 'Ry_eci';
            app.Ry_eciEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Ry_eciEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEci, true);
            app.Rz_eciEditFieldLabel = uilabel(app.LeftPanel);
            app.Rz_eciEditFieldLabel.Text = 'Rz_eci';
            app.Rz_eciEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Rz_eciEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEci, true);
            app.Vx_eciEditFieldLabel = uilabel(app.LeftPanel);
            app.Vx_eciEditFieldLabel.Text = 'Vx_eci';
            app.Vx_eciEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Vx_eciEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEci, true);
            app.Vy_eciEditFieldLabel = uilabel(app.LeftPanel);
            app.Vy_eciEditFieldLabel.Text = 'Vy_eci';
            app.Vy_eciEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Vy_eciEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEci, true);
            app.Vz_eciEditFieldLabel = uilabel(app.LeftPanel);
            app.Vz_eciEditFieldLabel.Text = 'Vz_eci';
            app.Vz_eciEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Vz_eciEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEci, true);
            app.Rx_ecefEditFieldLabel = uilabel(app.LeftPanel);
            app.Rx_ecefEditFieldLabel.Text = 'Rx_ecef';
            app.Rx_ecefEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Rx_ecefEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEcef, true);
            app.Ry_ecefEditFieldLabel = uilabel(app.LeftPanel);
            app.Ry_ecefEditFieldLabel.Text = 'Ry_ecef';
            app.Ry_ecefEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Ry_ecefEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEcef, true);
            app.Rz_ecefEditFieldLabel = uilabel(app.LeftPanel);
            app.Rz_ecefEditFieldLabel.Text = 'Rz_ecef';
            app.Rz_ecefEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Rz_ecefEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEcef, true);
            app.Vx_ecefEditFieldLabel = uilabel(app.LeftPanel);
            app.Vx_ecefEditFieldLabel.Text = 'Vx_ecef';
            app.Vx_ecefEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Vx_ecefEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEcef, true);
            app.Vy_ecefEditFieldLabel = uilabel(app.LeftPanel);
            app.Vy_ecefEditFieldLabel.Text = 'Vy_ecef';
            app.Vy_ecefEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Vy_ecefEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEcef, true);
            app.Vz_ecefEditFieldLabel = uilabel(app.LeftPanel);
            app.Vz_ecefEditFieldLabel.Text = 'Vz_ecef';
            app.Vz_ecefEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Vz_ecefEditField.ValueChangedFcn = createCallbackFcn(app, @updateFromEcef, true);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initialConditions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end